classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BasicButtonsButtonGroup        matlab.ui.container.ButtonGroup
        ConvertToGrayImageButton       matlab.ui.control.Button
        ConertToBinaryImageButton      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        OperationsOnImagesButtonGroup  matlab.ui.container.ButtonGroup
        AdditionButton                 matlab.ui.control.Button
        DivitionButton                 matlab.ui.control.Button
        SwappingRGButton               matlab.ui.control.Button
        SubtractButton                 matlab.ui.control.Button
        ComplementButton               matlab.ui.control.Button
        MultiplicationButton           matlab.ui.control.Button
        SwappingGBButton               matlab.ui.control.Button
        SwappingRBButton               matlab.ui.control.Button
        EliminatingRedButton           matlab.ui.control.Button
        EliminatingGreenButton         matlab.ui.control.Button
        EliminatingBlueButton          matlab.ui.control.Button
        SpiltRedChannelButton          matlab.ui.control.Button
        SpiltBlueChannelButton         matlab.ui.control.Button
        SpiltGreenChannelButton        matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        SelectFirstImageButton         matlab.ui.control.Button
        SelectSecondImageButton        matlab.ui.control.Button
        InputImage1Label               matlab.ui.control.Label
        InputImage2Label               matlab.ui.control.Label
        HistogramCalculationButtonGroup  matlab.ui.container.ButtonGroup
        HistogramButton                matlab.ui.control.Button
        HistogramStrechingButton       matlab.ui.control.Button
        HistogramEqualizationButton    matlab.ui.control.Button
        ApplyingFiltersButtonGroup     matlab.ui.container.ButtonGroup
        MinFilterButton                matlab.ui.control.Button
        MaxFilterButton                matlab.ui.control.Button
        MedianFilterButton             matlab.ui.control.Button
        AvrageFilterButton             matlab.ui.control.Button
        IncreasebluringButton          matlab.ui.control.Button
        LaplacianFilterButton          matlab.ui.control.Button
        OrderFilterButton              matlab.ui.control.Button
        OutPutImageLabel               matlab.ui.control.Label
        NoiseButtonGroup               matlab.ui.container.ButtonGroup
        SaltPapperNoiseButton          matlab.ui.control.Button
        GaussianNoiseButton            matlab.ui.control.Button
        DitheringButtonGroup           matlab.ui.container.ButtonGroup
        NaiveThresholdButton           matlab.ui.control.Button
        BrightnessThresholdButton      matlab.ui.control.Button
        ErrorDiffusionButton           matlab.ui.control.Button
        OrderedDitheringButton         matlab.ui.control.Button
        PatternDitheringButton         matlab.ui.control.Button
        ImageSegmentationButtonGroup   matlab.ui.container.ButtonGroup
        BasicGlobalThresholdingButton  matlab.ui.control.Button
        AutomaticthresholdingButton    matlab.ui.control.Button
        AdaptivethresholdingButton     matlab.ui.control.Button
        LocalthresholdingButton        matlab.ui.control.Button
        ChengJinKuosButton             matlab.ui.control.Button
        EdgeDetectionButtonGroup       matlab.ui.container.ButtonGroup
        SobeldetectorButton            matlab.ui.control.Button
        PrewittdetectorButton          matlab.ui.control.Button
        RobertsdetectorButton          matlab.ui.control.Button
        ImageTransitionsButtonGroup    matlab.ui.container.ButtonGroup
        CrossDissolveButton            matlab.ui.control.Button
        DissolvePushDownButton         matlab.ui.control.Button
        DissolvePushLeftButton         matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        img1 % Description
        img2 % Description
        img3 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertToGrayImageButton
        function convert2gray(app, event)
            
            app.img2=rgb2gray(app.img1);
            imshow(app.img2,'Parent',app.UIAxes2)
        end

        % Button pushed function: SelectFirstImageButton
        function select(app, event)
             [file , path]=uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            
            figure(app.UIFigure);
            app.img1=imread(fullfile(path,file));
            imshow(app.img1,'parent',app.UIAxes)
        end

        % Button pushed function: ConertToBinaryImageButton
        function convert2inary(app, event)
            l=graythresh(app.img1);
       app.img2=im2bw(app.img1,l);
       imshow(app.img2,'Parent',app.UIAxes2)
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            app.UIAxes.cla;
            app.UIAxes2.cla;
            app.UIAxes3.cla;
            app.img1="";
            app.img2="";
            app.img3="";
            title(app.UIAxes,"");
            title(app.UIAxes2,"");
            title(app.UIAxes3,"");
        end

        % Button pushed function: ExitButton
        function exit(app, event)
        quit(cancel);
        end

        % Button pushed function: SelectSecondImageButton
        function select2(app, event)
              [file , path]=uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            
            figure(app.UIFigure);
            app.img3=imread(fullfile(path,file));
            imshow(app.img3,'parent',app.UIAxes3)
        end

        % Button pushed function: AdditionButton
        function add(app, event)
          app.img1=imresize(app.img1,[400, 600]);
          app.img3=imresize(app.img3,[400,600]);
            add=imadd(app.img1,app.img3);
            imshow(add,'parent',app.UIAxes2);
        end

        % Button pushed function: SubtractButton
        function sub(app, event)
           app.img1=imresize(app.img1,[400, 600]);
           app.img3=imresize(app.img3,[400,600]);
            sub=imsubtract(app.img1,app.img3);
            imshow(sub,'Parent',app.UIAxes2);
        end

        % Button pushed function: MultiplicationButton
        function mult(app, event)
           app.img1=imresize(app.img1,[400, 600]);
           app.img3=imresize(app.img3,[400,600]);
            mult=immultiply(app.img1,app.img3);
            imshow(mult,'Parent',app.UIAxes2);
        end

        % Button pushed function: DivitionButton
        function div(app, event)
            app.img1=imresize(app.img1,[400, 600]);
           app.img3=imresize(app.img3,[400,600]);
            divide=imdivide(app.img1,app.img3);
           imshow(divide,'Parent',app.UIAxes2);
        end

        % Button pushed function: ComplementButton
        function complement(app, event)
            comp1=imcomplement(app.img1);
            imshow(comp1,'Parent',app.UIAxes2);
        end

        % Button pushed function: SwappingRBButton
        function SwappingRandB(app, event)
               ch1 = app.img1(:,:,1);
            ch2 = app.img1(:,:,2);
            ch3 = app.img1(:,:,3);
            x1= cat(3, ch3, ch2, ch1);
            imshow(x1,'Parent',app.UIAxes2);

            
        end

        % Button pushed function: SwappingRGButton
        function swapping_RandG(app, event)
             ch1 = app.img1(:,:,1);
            ch2 = app.img1(:,:,2);
            ch3 = app.img1(:,:,3);
            x1= cat(3, ch2, ch1, ch3);
            imshow(x1,'Parent',app.UIAxes2);
        end

        % Button pushed function: SwappingGBButton
        function swappingGandB(app, event)
                      ch1 = app.img1(:,:,1);
            ch2 = app.img1(:,:,2);
            ch3 = app.img1(:,:,3);
            x1= cat(3, ch1, ch3, ch2);
            imshow(x1,'Parent',app.UIAxes2);
        end

        % Button pushed function: EliminatingRedButton
        function EliminatingR(app, event)
            GB = app.img1;
            GB (:,:,1)=0;
            imshow(GB,'Parent',app.UIAxes2);

        end

        % Button pushed function: EliminatingGreenButton
        function EliminatingG(app, event)
              GB = app.img1;
            GB (:,:,2)=0;
            imshow(GB,'Parent',app.UIAxes2);
        end

        % Button pushed function: EliminatingBlueButton
        function EliminatingB(app, event)
              GB = app.img1;
            GB (:,:,3)=0;
            imshow(GB,'Parent',app.UIAxes2);
        end

        % Callback function
        function save(app, event)
    startingFolder = userpath;
     defaultFileName = fullfile(startingFolder, '*.*');
    [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
     if baseFileName == 0
  % User clicked the Cancel button.
       return;
     end
    fullFileName = fullfile(folder, baseFileName);
     imwrite(app.img2, fullFileName);
        end

        % Button pushed function: HistogramButton
        function histogram(app, event)
         gray=rgb2gray(app.img1);
            imhist(app.img1);title('Histogram');
            imshow(gray,'Parent',app.UIAxes2);
%             [counts,binLocations] = imhist(gray);
%  stem(app.UIAxes2,binLocations,counts);
%             imshow(x,'Parent',app.UIAxes2);
            
        end

        % Button pushed function: AvrageFilterButton
        function avg(app, event)
            r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            z=fspecial('average');
            b1=imfilter(r,z);
            b2=imfilter(g,z);
            b3=imfilter(b,z);
            c = cat (3,b1,b2,b3);
            imshow(c,'Parent',app.UIAxes2)


        end

        % Button pushed function: IncreasebluringButton
        function bluring(app, event)
           gray=rgb2gray(app.img1);  
            mask=fspecial('average',[7,7]);
            img=filter2(mask,gray,'same');
            img=uint8(img);
            imshow(img,'Parent',app.UIAxes2);
        end

        % Button pushed function: LaplacianFilterButton
        function Laplacian(app, event)
                   r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            z=fspecial('laplacian');
            b1=imfilter(r,z);
            b2=imfilter(g,z);
            b3=imfilter(b,z);
            c = cat (3,b1,b2,b3);
            imshow(c,'Parent',app.UIAxes2)
        end

        % Button pushed function: MaxFilterButton
        function max(app, event)
            r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            b1=colfilt(r,[5 5],'sliding',@max);
            b2=colfilt(g,[5 5],'sliding',@max);
            b3=colfilt(b,[5 5],'sliding',@max);
            c = cat (3,b1,b2,b3);
     
            imshow(c,'Parent',app.UIAxes2)
            
        end

        % Button pushed function: MinFilterButton
        function min(app, event)
            r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            b1=colfilt(r,[5 5],'sliding',@min);
            b2=colfilt(g,[5 5],'sliding',@min);
            b3=colfilt(b,[5 5],'sliding',@min);
            c = cat (3,b1,b2,b3);
            imshow(c,'Parent',app.UIAxes2)
            
        end

        % Button pushed function: MedianFilterButton
        function Median(app, event)
            r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            b1=colfilt(r,[5 5],'sliding',@median);
            b2=colfilt(g,[5 5],'sliding',@median);
            b3=colfilt(b,[5 5],'sliding',@median);
            c = cat (3,b1,b2,b3);
            imshow(c,'Parent',app.UIAxes2)
         
        end

        % Button pushed function: HistogramStrechingButton
        function streching(app, event)
            gray=rgb2gray(app.img1);
            oldMin = min(gray(:));
            oldMax = max(gray(:));
            newMin = 0;
            newMax = 255;
            gamma = 1.5;
           old = double(gray);
           new = ((old - double(oldMin))/double(oldMax-oldMin)) .^ gamma * double(newMax - newMin) + double(newMin);
           new =  uint8(new);
           imshow(new,'Parent',app.UIAxes2);
           imhist(new);title('After Streching');

        end

        % Button pushed function: HistogramEqualizationButton
        function equalization(app, event)
            J = histeq(app.img1);
           imshow(J,'parent',app.UIAxes2);
           imhist(J);title('After Equalization');
        end

        % Button pushed function: SaltPapperNoiseButton
        function s(app, event)

 app.img1= imnoise(app.img1,'salt & pepper',.05);
 imshow(app.img1,'parent',app.UIAxes3)
        end

        % Button pushed function: GaussianNoiseButton
        function g(app, event)
            app.img1= imnoise(app.img1,'gaussian');
            imshow(app.img1,'parent',app.UIAxes3)
        end

        % Button pushed function: OrderFilterButton
        function o(app, event)
            r = app.img1(:,:,1);
            g = app.img1(:,:,2);
            b = app.img1(:,:,3);
            b1=ordfilt2 (r,5,ones(3,3));
            b2=ordfilt2 (g,5,ones(3,3));
            b3=ordfilt2 (b,5,ones(3,3));
            c = cat(3,b1,b2,b3);
            imshow(c,'parent',app.UIAxes2)
        end

        % Button pushed function: SpiltRedChannelButton
        function redspliter(app, event)
           r = app.img1(:,:,1);
           imshow(r,'parent',app.UIAxes2)
        end

        % Button pushed function: SpiltBlueChannelButton
        function bluespliter(app, event)
            b = app.img1(:,:,3);
           imshow(b,'parent',app.UIAxes2)
        end

        % Button pushed function: SpiltGreenChannelButton
        function greenspliter(app, event)
            g= app.img1(:,:,2);
           imshow(g,'parent',app.UIAxes2)
        end

        % Button pushed function: NaiveThresholdButton
        function nave(app, event)
              gray=rgb2gray(app.img1);
            [row col]=size(gray);
for i=1:row
    for j=1:col
    if(gray(i,j)>=128)
      naive(i,j)=255;
    else
      naive(i,j)=0;  
    end
    end
end

imshow(naive,'Parent',app.UIAxes2);

        end

        % Button pushed function: BrightnessThresholdButton
        function britness(app, event)
               gray=rgb2gray(app.img1);
            [row col]=size(gray);
            m=mean2(gray);
for i=1:row
    for j=1:col
    if(gray(i,j)>=m)
      bright(i,j)=255;
    else
      bright(i,j)=0;  
    end
    end
end

imshow(bright,'Parent',app.UIAxes2);

        end

        % Button pushed function: BasicGlobalThresholdingButton
        function basic_global(app, event)
app.img1=rgb2gray(app.img1);
imshow(app.img1>130,'Parent',app.UIAxes2)
        end

        % Button pushed function: AutomaticthresholdingButton
        function auto_threshold(app, event)
           
Theta = mean2(app.img1); 
 done = 0;
 while ~done 
g = (app.img1 >=Theta);
m1=mean(app.img1(g));
m2=mean(app.img1(~g));
Th_next = 0.5*( m1+ m2);   
done = abs(Theta - Th_next) < 0.5;   
Theta = Th_next;   
 end 
 x=im2bw(app.img1,Theta/255);
 
 imshow(x,'Parent',app.UIAxes2)

        end

        % Button pushed function: AdaptivethresholdingButton
        function adaptive_threshold(app, event)
            
f=imfilter(app.img1,fspecial('average',[195 195]));
diff=abs(app.img1-f);
bw=im2bw(diff,0);
imshow(bw,'Parent',app.UIAxes2)

        end

        % Button pushed function: ChengJinKuosButton
        function cheng(app, event)

c=rgb2gray(app.img1);
p1=c(:,1:64);
p2=c(:,65:128);
p3=c(:,129:192);
p4=c(:,193:256);
t1=im2bw(p1,graythresh(p1));
t2=im2bw(p2,graythresh(p2));
t3=im2bw(p3,graythresh(p3));
t4=im2bw(p4,graythresh(p4));
x=[t1 t2 t3 t4];
imshow(x,'Parent',app.UIAxes2)

        end

        % Button pushed function: SobeldetectorButton
        function sobl_det(app, event)
   image=rgb2gray(app.img1); 
             edge_s=edge(image,'sobel');
          imshow(edge_s,'Parent',app.UIAxes2) 
        end

        % Button pushed function: PrewittdetectorButton
        function prewit_det(app, event)

            image=rgb2gray(app.img1); 
             edge_p=edge(image,'prewitt');
          imshow(edge_p,'Parent',app.UIAxes2) 
        end

        % Button pushed function: RobertsdetectorButton
        function roberts_det(app, event)
              image=rgb2gray(app.img1); 
             edge_r=edge(image,'roberts');
          imshow(edge_r,'Parent',app.UIAxes2) 
        end

        % Button pushed function: OrderedDitheringButton
        function orderd_dither(app, event)
image1=rgb2gray(app.img1);
threshold=[0 128 32 160 
          192 64 224 96
          48  176 16 144
          240 112 208 80];
 
      
 [row , col]=size(image1);
 
 for x=1:row
     for y=1:col
         i=mod(x,4);
         j=mod(y,4);
         if(i==0)
             i=4;
             
         end
         if(j==0)
             j=4;
         end
         if(image1(x,y)>threshold(i,j))
             image1(x,y)=255;
         else
             image1(x,y)=0;
         end
     end
 end

imshow(image1,'Parent',app.UIAxes2)
        end

        % Button pushed function: ErrorDiffusionButton
        function error_diff(app, event)
            old=rgb2gray(app.img1);
            [row , col] = size(old);
            new = old;
            for i=1 : row 
                for j =1 :col
                    temp = new(i,j);
                    if (new(i,j)>=128)
                        new(i,j)=255;
                    else
                        new(i,j)=0;
                    end
                    error = temp-new(i,j);
                    if(i ~= row ) && (j==1)
                        new(i,j+1) = new(i,j+1) + 7/16 * error;
                        new(i+1,j) = new(i+1,j) + 5/16 * error;
                        new(i+1,j+1) = new(i+1,j+1) + 1/16 * error;
                    elseif (i==row) && (j ~= col)
                        new(i,j+1) = new(i,j+1) + 7/16 * error;
                    elseif (i~=row) && (j == col)
                        new(i+1,j-1) = new(i+1,j-1) + 3/16 * error;
                        new(i+1,j) = new(i+1,j) + 5/16 * error;
                    elseif (i==row) && (j==col)
                        continue
                    else
                        new(i,j+1) = new(i,j+1) + 7/16 * error;
                        new(i+1,j-1) = new(i+1,j-1) + 3/16 * error;
                        new(i+1,j) = new(i+1,j) + 5/16 * error;
                        new(i+1,j+1) = new(i+1,j+1) + 1/16 * error;
                    end
                end
            end
            imshow(new,'Parent',app.UIAxes2)
        end

        % Button pushed function: PatternDitheringButton
        function pattern_dith(app, event)
  image1 = rgb2gray(app.img1);
  pat1 = [0 0;0 0]; %range 0 50
pat2 = [0 255;0 0];%range 50 100
pat3 = [0 255;255 0];%range 100 150
pat4 = [255 255;255 0];%range 150 200
pat5 = [255 255;255 255];%range 200 255
[row, col] = size(image1);
for i = 1:2:row
    for j=1:2:col
        slice = image1(i:i+1,j:j+1);
        avg = sum(sum(slice))/4;
        if(avg<50)
            image1(i:i+1,j:j+1) = pat1;
        elseif((avg>=50)&&(avg<100))
            image1(i:i+1,j:j+1) = pat2;
         elseif((avg>=100)&&(avg<150))
              image1(i:i+1,j:j+1) = pat3;
         elseif((avg>=150)&&(avg<200))
              image1(i:i+1,j:j+1) = pat4;
         elseif((avg>=200)&&(avg<255))
              image1(i:i+1,j:j+1) = pat5;
        end
    end
end

imshow(image1,'Parent',app.UIAxes2)

        end

        % Button pushed function: CrossDissolveButton
        function cross_dis(app, event)
            value=0.01;
            for i=1:100
            final=app.img1*value+app.img3*(1-value);
            imshow(final,'Parent',app.UIAxes2);
            value=value+0.01;
            pause(0.01)
            end
        end

        % Button pushed function: DissolvePushDownButton
        function Dither_dissolve(app, event)


[row,col,ch]=size(app.img1);

imshow(app.img1,'Parent',app.UIAxes2)
pause(0.5)
app.img1(1:.3*row,:,:)=app.img3(1:.3*row,:,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(.3*row-1:.3*row*2,:,:)=app.img3(.3*row-1:.3*row*2,:,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(.3*row*2-1:.3*row*3,:,:)=app.img3(.3*row*2-1:.3*row*3,:,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(.3*row*3-1:row,:,:)=app.img3(.3*row*3-1:row,:,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)

        end

        % Button pushed function: DissolvePushLeftButton
        function dither_left(app, event)
  
[row,col,ch]=size(app.img1);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
%alpha = 0.3
app.img1(:,1:.3*col,:)=app.img3(:,1:.3*col,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(:,.3*col+1:.3*col*2,:)=app.img3(:,.3*col+1:.3*col*2,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(:,.3*col*2+1:.3*col*3,:)=app.img3(:,.3*col*2+1:.3*col*3,:);
imshow(app.img1,'Parent',app.UIAxes2)

pause(0.5)
app.img1(:,.3*col*3+1:col,:)=app.img3(:,.3*col*3+1:col,:);
imshow(app.img1,'Parent',app.UIAxes2)
title('Dither Dissolve left> right ')
pause(0.5)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BasicButtonsButtonGroup = uibuttongroup(app.UIFigure);
            app.BasicButtonsButtonGroup.Title = 'Basic Buttons';
            app.ConvertToGrayImageButton = uibutton(app.BasicButtonsButtonGroup, 'push');
            app.ConvertToGrayImageButton.ButtonPushedFcn = createCallbackFcn(app, @convert2gray, true);
            app.ConvertToGrayImageButton.Text = 'Convert To Gray Image';
            app.ConertToBinaryImageButton = uibutton(app.BasicButtonsButtonGroup, 'push');
            app.ConertToBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @convert2inary, true);
            app.ConertToBinaryImageButton.Text = 'Conert To Binary Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.OperationsOnImagesButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationsOnImagesButtonGroup.Title = 'Operations On Images';
            app.AdditionButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.AdditionButton.ButtonPushedFcn = createCallbackFcn(app, @add, true);
            app.AdditionButton.Text = 'Addition';
            app.DivitionButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.DivitionButton.ButtonPushedFcn = createCallbackFcn(app, @div, true);
            app.DivitionButton.Text = 'Divition';
            app.SwappingRGButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SwappingRGButton.ButtonPushedFcn = createCallbackFcn(app, @swapping_RandG, true);
            app.SwappingRGButton.Text = 'Swapping R&G';
            app.SubtractButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @sub, true);
            app.SubtractButton.Text = 'Subtract';
            app.ComplementButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @complement, true);
            app.ComplementButton.Text = 'Complement';
            app.MultiplicationButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @mult, true);
            app.MultiplicationButton.Text = 'Multiplication';
            app.SwappingGBButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SwappingGBButton.ButtonPushedFcn = createCallbackFcn(app, @swappingGandB, true);
            app.SwappingGBButton.Text = 'Swapping G&B ';
            app.SwappingRBButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SwappingRBButton.ButtonPushedFcn = createCallbackFcn(app, @SwappingRandB, true);
            app.SwappingRBButton.Text = 'Swapping R&B';
            app.EliminatingRedButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.EliminatingRedButton.ButtonPushedFcn = createCallbackFcn(app, @EliminatingR, true);
            app.EliminatingRedButton.Text = 'Eliminating Red';
            app.EliminatingGreenButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.EliminatingGreenButton.ButtonPushedFcn = createCallbackFcn(app, @EliminatingG, true);
            app.EliminatingGreenButton.Text = 'Eliminating Green';
            app.EliminatingBlueButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.EliminatingBlueButton.ButtonPushedFcn = createCallbackFcn(app, @EliminatingB, true);
            app.EliminatingBlueButton.Text = 'Eliminating Blue';
            app.SpiltRedChannelButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SpiltRedChannelButton.ButtonPushedFcn = createCallbackFcn(app, @redspliter, true);
            app.SpiltRedChannelButton.Text = 'Spilt Red Channel';
            app.SpiltBlueChannelButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SpiltBlueChannelButton.ButtonPushedFcn = createCallbackFcn(app, @bluespliter, true);
            app.SpiltBlueChannelButton.Text = 'Spilt Blue Channel';
            app.SpiltGreenChannelButton = uibutton(app.OperationsOnImagesButtonGroup, 'push');
            app.SpiltGreenChannelButton.ButtonPushedFcn = createCallbackFcn(app, @greenspliter, true);
            app.SpiltGreenChannelButton.Text = 'Spilt Green Channel';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.SelectFirstImageButton = uibutton(app.UIFigure, 'push');
            app.SelectFirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.SelectFirstImageButton.Text = 'Select First Image';
            app.SelectSecondImageButton = uibutton(app.UIFigure, 'push');
            app.SelectSecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @select2, true);
            app.SelectSecondImageButton.Text = 'Select Second Image';
            app.InputImage1Label = uilabel(app.UIFigure);
            app.InputImage1Label.Text = 'Input Image1';
            app.InputImage2Label = uilabel(app.UIFigure);
            app.InputImage2Label.Text = 'Input Image2';
            app.HistogramCalculationButtonGroup = uibuttongroup(app.UIFigure);
            app.HistogramCalculationButtonGroup.Title = 'Histogram Calculation';
            app.HistogramButton = uibutton(app.HistogramCalculationButtonGroup, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @histogram, true);
            app.HistogramButton.Text = 'Histogram';
            app.HistogramStrechingButton = uibutton(app.HistogramCalculationButtonGroup, 'push');
            app.HistogramStrechingButton.ButtonPushedFcn = createCallbackFcn(app, @streching, true);
            app.HistogramStrechingButton.Text = 'Histogram Streching';
            app.HistogramEqualizationButton = uibutton(app.HistogramCalculationButtonGroup, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @equalization, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.ApplyingFiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.ApplyingFiltersButtonGroup.Title = 'Applying Filters';
            app.MinFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.MinFilterButton.ButtonPushedFcn = createCallbackFcn(app, @min, true);
            app.MinFilterButton.Text = 'Min Filter';
            app.MaxFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.MaxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @max, true);
            app.MaxFilterButton.Text = 'Max Filter';
            app.MedianFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @Median, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.AvrageFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.AvrageFilterButton.ButtonPushedFcn = createCallbackFcn(app, @avg, true);
            app.AvrageFilterButton.Text = 'Avrage Filter';
            app.IncreasebluringButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.IncreasebluringButton.ButtonPushedFcn = createCallbackFcn(app, @bluring, true);
            app.IncreasebluringButton.Text = 'Increase bluring';
            app.LaplacianFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.LaplacianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @Laplacian, true);
            app.LaplacianFilterButton.Text = 'Laplacian Filter';
            app.OrderFilterButton = uibutton(app.ApplyingFiltersButtonGroup, 'push');
            app.OrderFilterButton.ButtonPushedFcn = createCallbackFcn(app, @o, true);
            app.OrderFilterButton.Text = 'Order Filter';
            app.OutPutImageLabel = uilabel(app.UIFigure);
            app.OutPutImageLabel.Text = 'OutPut Image';
            app.NoiseButtonGroup = uibuttongroup(app.UIFigure);
            app.NoiseButtonGroup.Title = 'Noise';
            app.SaltPapperNoiseButton = uibutton(app.NoiseButtonGroup, 'push');
            app.SaltPapperNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @s, true);
            app.SaltPapperNoiseButton.Text = 'Salt&Papper Noise';
            app.GaussianNoiseButton = uibutton(app.NoiseButtonGroup, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @g, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.DitheringButtonGroup = uibuttongroup(app.UIFigure);
            app.DitheringButtonGroup.Title = 'Dithering';
            app.NaiveThresholdButton = uibutton(app.DitheringButtonGroup, 'push');
            app.NaiveThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @nave, true);
            app.NaiveThresholdButton.Text = {'Naive Threshold '; ''};
            app.BrightnessThresholdButton = uibutton(app.DitheringButtonGroup, 'push');
            app.BrightnessThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @britness, true);
            app.BrightnessThresholdButton.Text = {'Brightness Threshold'; ''};
            app.ErrorDiffusionButton = uibutton(app.DitheringButtonGroup, 'push');
            app.ErrorDiffusionButton.ButtonPushedFcn = createCallbackFcn(app, @error_diff, true);
            app.ErrorDiffusionButton.Text = 'Error Diffusion';
            app.OrderedDitheringButton = uibutton(app.DitheringButtonGroup, 'push');
            app.OrderedDitheringButton.ButtonPushedFcn = createCallbackFcn(app, @orderd_dither, true);
            app.OrderedDitheringButton.Text = 'Ordered Dithering';
            app.PatternDitheringButton = uibutton(app.DitheringButtonGroup, 'push');
            app.PatternDitheringButton.ButtonPushedFcn = createCallbackFcn(app, @pattern_dith, true);
            app.PatternDitheringButton.Text = 'Pattern Dithering';
            app.ImageSegmentationButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageSegmentationButtonGroup.Title = 'Image Segmentation‏';
            app.BasicGlobalThresholdingButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.BasicGlobalThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @basic_global, true);
            app.BasicGlobalThresholdingButton.Text = 'Basic Global Thresholding';
            app.AutomaticthresholdingButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.AutomaticthresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @auto_threshold, true);
            app.AutomaticthresholdingButton.Text = 'Automatic thresholding';
            app.AdaptivethresholdingButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.AdaptivethresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @adaptive_threshold, true);
            app.AdaptivethresholdingButton.Text = {'Adaptive thresholding'; ''};
            app.LocalthresholdingButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.LocalthresholdingButton.Text = 'Local thresholding';
            app.ChengJinKuosButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.ChengJinKuosButton.ButtonPushedFcn = createCallbackFcn(app, @cheng, true);
            app.ChengJinKuosButton.Text = 'Cheng-Jin Kuo`s';
            app.EdgeDetectionButtonGroup = uibuttongroup(app.UIFigure);
            app.EdgeDetectionButtonGroup.Title = 'Edge Detection';
            app.SobeldetectorButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.SobeldetectorButton.ButtonPushedFcn = createCallbackFcn(app, @sobl_det, true);
            app.SobeldetectorButton.Text = ' Sobel detector';
            app.PrewittdetectorButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.PrewittdetectorButton.ButtonPushedFcn = createCallbackFcn(app, @prewit_det, true);
            app.PrewittdetectorButton.Text = 'Prewitt detector';
            app.RobertsdetectorButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.RobertsdetectorButton.ButtonPushedFcn = createCallbackFcn(app, @roberts_det, true);
            app.RobertsdetectorButton.Text = 'Roberts detector';
            app.ImageTransitionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageTransitionsButtonGroup.Title = 'Image Transitions';
            app.CrossDissolveButton = uibutton(app.ImageTransitionsButtonGroup, 'push');
            app.CrossDissolveButton.ButtonPushedFcn = createCallbackFcn(app, @cross_dis, true);
            app.CrossDissolveButton.Text = 'Cross Dissolve';
            app.DissolvePushDownButton = uibutton(app.ImageTransitionsButtonGroup, 'push');
            app.DissolvePushDownButton.ButtonPushedFcn = createCallbackFcn(app, @Dither_dissolve, true);
            app.DissolvePushDownButton.Text = 'Dissolve (Push Down)';
            app.DissolvePushLeftButton = uibutton(app.ImageTransitionsButtonGroup, 'push');
            app.DissolvePushLeftButton.ButtonPushedFcn = createCallbackFcn(app, @dither_left, true);
            app.DissolvePushLeftButton.Text = 'Dissolve (Push Left)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end